classdef GIPPO_Beta_1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LogoCreditFedericoFarinaLabel  matlab.ui.control.Label
        GraphbasedIterativePrintingPathOptimizationLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        StartparallelcomputingprocessorsButton  matlab.ui.control.Button
        GIPPOLabel                     matlab.ui.control.Label
        MaxbasedoptimizationButton     matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        SaveOptimizedPathButton        matlab.ui.control.Button
        PathoptimizedCheckBox          matlab.ui.control.CheckBox
        DirectoryLoadedCheckBox        matlab.ui.control.CheckBox
        MinbasedoptimizationButton     matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        selected; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MinbasedoptimizationButton
        function MinbasedoptimizationButtonPushed(app, event)
            set(app.DirectoryLoadedCheckBox,'Value',0)        
            set(app.PathoptimizedCheckBox,'Value',0);
            set(app.SaveOptimizedPathButton,'Visible','off')
            set(app.TextArea,'Value','Loading...')       
            app.selected=GIPPO_min_MultiL(app.DirectoryLoadedCheckBox,...
                app.PathoptimizedCheckBox,...
                app.TextArea,app.SaveOptimizedPathButton);
        end

        % Value changed function: DirectoryLoadedCheckBox
        function DirectoryLoadedCheckBoxValueChanged(app, event)
            value = app.DirectoryLoadedCheckBox.Value;
            
        end

        % Value changed function: PathoptimizedCheckBox
        function PathoptimizedCheckBoxValueChanged(app, event)
            value = app.PathoptimizedCheckBox.Value;
            
        end

        % Button pushed function: SaveOptimizedPathButton
        function SaveOptimizedPathButtonPushed(app, event)
            save_file_GIPPO_MultiL(app.selected,app.TextArea)
        end

        % Value changing function: TextArea
        function TextAreaValueChanging(app, event)
            changingValue = event.Value;
          
            
        end

        % Button pushed function: MaxbasedoptimizationButton
        function MaxbasedoptimizationButtonPushed(app, event)
            set(app.DirectoryLoadedCheckBox,'Value',0)            
            set(app.PathoptimizedCheckBox,'Value',0);
            set(app.SaveOptimizedPathButton,'Visible','off')
            set(app.TextArea,'Value','Loading...')
            app.selected=GIPPO_max_MultiL(app.DirectoryLoadedCheckBox,...
                app.PathoptimizedCheckBox,...
                app.TextArea,app.SaveOptimizedPathButton);
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end

        % Button pushed function: StartparallelcomputingprocessorsButton
        function StartparallelcomputingprocessorsButtonPushed(app, event)
            set(app.TextArea,'Value','Starting Parallel Computing Clients...')
            parfor i=1:50
                var=i;
            end
            set(app.TextArea,'Value','Parallel Computing Clients successfully started!')
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MinbasedoptimizationButton = uibutton(app.UIFigure, 'push');
            app.MinbasedoptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @MinbasedoptimizationButtonPushed, true);
            app.MinbasedoptimizationButton.Text = 'Min-based optimization';
            app.DirectoryLoadedCheckBox = uicheckbox(app.UIFigure);
            app.DirectoryLoadedCheckBox.ValueChangedFcn = createCallbackFcn(app, @DirectoryLoadedCheckBoxValueChanged, true);
            app.DirectoryLoadedCheckBox.Text = 'Directory Loaded';
            app.PathoptimizedCheckBox = uicheckbox(app.UIFigure);
            app.PathoptimizedCheckBox.ValueChangedFcn = createCallbackFcn(app, @PathoptimizedCheckBoxValueChanged, true);
            app.PathoptimizedCheckBox.Text = 'Path optimized!';
            app.SaveOptimizedPathButton = uibutton(app.UIFigure, 'push');
            app.SaveOptimizedPathButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOptimizedPathButtonPushed, true);
            app.SaveOptimizedPathButton.Visible = 'off';
            app.SaveOptimizedPathButton.Text = 'Save Optimized Path';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.ValueChangingFcn = createCallbackFcn(app, @TextAreaValueChanging, true);
            app.TextArea.Editable = 'off';
            app.MaxbasedoptimizationButton = uibutton(app.UIFigure, 'push');
            app.MaxbasedoptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @MaxbasedoptimizationButtonPushed, true);
            app.MaxbasedoptimizationButton.Text = 'Max-based optimization';
            app.GIPPOLabel = uilabel(app.UIFigure);
            app.GIPPOLabel.Text = 'GIPPO';
            app.StartparallelcomputingprocessorsButton = uibutton(app.UIFigure, 'push');
            app.StartparallelcomputingprocessorsButton.ButtonPushedFcn = createCallbackFcn(app, @StartparallelcomputingprocessorsButtonPushed, true);
            app.StartparallelcomputingprocessorsButton.Text = {'Start parallel '; 'computing processors'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'GIPPO_Logo.svg');
            app.GraphbasedIterativePrintingPathOptimizationLabel = uilabel(app.UIFigure);
            app.GraphbasedIterativePrintingPathOptimizationLabel.Text = {'Graph-based Iterative'; 'Printing Path Optimization'};
            app.LogoCreditFedericoFarinaLabel = uilabel(app.UIFigure);
            app.LogoCreditFedericoFarinaLabel.Text = 'Logo Credit: Federico Farina';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GIPPO_Beta_1_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end